classdef digi2excel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        digi2excelUIFigure    matlab.ui.Figure
        EasyGuideButton       matlab.ui.control.Button
        CMDropDown            matlab.ui.control.DropDown
        CMDropDownLabel       matlab.ui.control.Label
        CButton               matlab.ui.control.Button
        TestLogButton         matlab.ui.control.Button
        LogAreaTextArea       matlab.ui.control.TextArea
        LogAreaTextAreaLabel  matlab.ui.control.Label
        BeginButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        StrArray % for logging
    end
    
    methods (Access = private)
        
        function log_func(app, logStr)
            app.StrArray = [app.StrArray, {sprintf(logStr)}]; % sprintf为了转义
            app.StrArray = [app.StrArray, {sprintf('----------+++----------')}];
            app.LogAreaTextArea.Value = app.StrArray;
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.digi2excelUIFigure, 'northwest');
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            className = class(app);  % 获取当前app的类名
            classPath = which(className);
            log_func(app, ['APPPath:  ', classPath])

            a = uigetdir();
            log_func(app, ['NewPath:  ', a])
            cd(a);
            
            log_func(app, ['CurrentPath:  ', pwd])
            
            logging = digitalMicDataTool(app.CMDropDown.Value);
            log_func(app, logging)
        end

        % Callback function
        function TestLogButtonPushed(app, event)

        end

        % Button pushed function: TestLogButton
        function TestLogButtonPushed2(app, event)
            for i = 1:6
                pause(0.1);
                log_func(app, char(string(i)));
            end            
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.StrArray = '';
            app.LogAreaTextArea.Value = '';
        end

        % Button pushed function: EasyGuideButton
        function EasyGuideButtonPushed(app, event)
            msg = sprintf(['                               ## How to use this app ## \n' ...
                '1. "Headers.csv" for all the items (change freely) \n' ...
                '2. "rawData.csv" for the rawData need to clean (should be osens digitalMic data) \n' ...
                '3. Headers.csv and rawData.csv files should be in same folder \n' ...
                '4. normal item head in Headers.csv need complete match \n' ...
                '5. launch "digi2excel.app" with "matlab r2022a runtime engine" to make data.csv \n']);
            msgbox(msg, 'TIPS', 'help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.digi2excelUIFigure = uifigure('Visible', 'off');
            app.digi2excelUIFigure.Name = 'digi2excel';
            app.BeginButton = uibutton(app.digi2excelUIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.LogAreaTextAreaLabel = uilabel(app.digi2excelUIFigure);
            app.LogAreaTextAreaLabel.Text = 'Log Area';
            app.LogAreaTextArea = uitextarea(app.digi2excelUIFigure);
            app.TestLogButton = uibutton(app.digi2excelUIFigure, 'push');
            app.TestLogButton.ButtonPushedFcn = createCallbackFcn(app, @TestLogButtonPushed2, true);
            app.TestLogButton.Text = 'TestLog';
            app.CButton = uibutton(app.digi2excelUIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.CMDropDownLabel = uilabel(app.digi2excelUIFigure);
            app.CMDropDownLabel.Text = 'CM';
            app.CMDropDown = uidropdown(app.digi2excelUIFigure);
            app.CMDropDown.Items = {'Mflex', 'Flexium', 'QSMC', 'WTKM', 'FXCD'};
            app.CMDropDown.Value = 'Mflex';
            app.EasyGuideButton = uibutton(app.digi2excelUIFigure, 'push');
            app.EasyGuideButton.ButtonPushedFcn = createCallbackFcn(app, @EasyGuideButtonPushed, true);
            app.EasyGuideButton.Text = {'Easy'; 'Guide'};
            % Show the figure after all components are created
            app.digi2excelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = digi2excel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.digi2excelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.digi2excelUIFigure)
        end
    end
end